classdef BasicGUIIdea < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InsertQueryHereLabel            matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SplashPageTab                   matlab.ui.container.Tab
        ElectronicMedicalRecordAccessPortalEMRAPLabel  matlab.ui.control.Label
        AccessLevelDropDownLabel        matlab.ui.control.Label
        AccessLevelDropDown             matlab.ui.control.DropDown
        SubmitTroubleTicketButton       matlab.ui.control.Button
        ViewHIPAAPrivacyInformationButton  matlab.ui.control.Button
        LoginButton                     matlab.ui.control.Button
        WhoAreYouEditFieldLabel         matlab.ui.control.Label
        WhoAreYouEditField              matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        PasswordEditField               matlab.ui.control.EditField
        AdminTab                        matlab.ui.container.Tab
        PersonnelActionsDropDownLabel   matlab.ui.control.Label
        PersonnelActionsDropDown        matlab.ui.control.DropDown
        EMRAPAdminLabel                 matlab.ui.control.Label
        SystemActionsDropDownLabel      matlab.ui.control.Label
        SystemActionsDropDown           matlab.ui.control.DropDown
        NameSSNEditFieldLabel           matlab.ui.control.Label
        NameSSNEditField                matlab.ui.control.EditField
        CircuitCriteriaEditFieldLabel   matlab.ui.control.Label
        CircuitCriteriaEditField        matlab.ui.control.EditField
        EnterButton                     matlab.ui.control.Button
        EnterButton_2                   matlab.ui.control.Button
        SwitchRoleButton                matlab.ui.control.Button
        RoleDropDownLabel               matlab.ui.control.Label
        RoleDropDown                    matlab.ui.control.DropDown
        CircuitLocationEditFieldLabel   matlab.ui.control.Label
        CircuitLocationEditField        matlab.ui.control.EditField
        LogoutButton_3                  matlab.ui.control.Button
        AdminPersonnelTab               matlab.ui.container.Tab
        EMRAPAdminPersonnelResultsLabel  matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        SearchAgainButton               matlab.ui.control.Button
        LogoutButton_4                  matlab.ui.control.Button
        AdminCircuitTab                 matlab.ui.container.Tab
        EMRAPAdminCircuitResultsLabel   matlab.ui.control.Label
        UITable4                        matlab.ui.control.Table
        SearchAgainButton_2             matlab.ui.control.Button
        LogoutButton_5                  matlab.ui.control.Button
        MedProfPatientSearchTab         matlab.ui.container.Tab
        PatientLastNameEditFieldLabel   matlab.ui.control.Label
        PatientLastNameEditField        matlab.ui.control.EditField
        DataofInterestEditFieldLabel    matlab.ui.control.Label
        DataofInterestEditField         matlab.ui.control.EditField
        GoGetitButton                   matlab.ui.control.Button
        EMRAPPatientSearchLabel         matlab.ui.control.Label
        ViewDropDownLabel               matlab.ui.control.Label
        ViewDropDown                    matlab.ui.control.DropDown
        PatientFirstNameEditField_2Label  matlab.ui.control.Label
        PatientFirstNameEditField_2     matlab.ui.control.EditField
        LogoutButton_6                  matlab.ui.control.Button
        HomeButton                      matlab.ui.control.Button
        MedProfCircuitSearchTab         matlab.ui.container.Tab
        CircuitIDsEditFieldLabel        matlab.ui.control.Label
        CircuitIDsEditField             matlab.ui.control.EditField
        DataofInterestEditField_2Label  matlab.ui.control.Label
        DataofInterestEditField_2       matlab.ui.control.EditField
        GoGetitButton_2                 matlab.ui.control.Button
        EMRAPCircuitSearchLabel         matlab.ui.control.Label
        ViewDropDown_2Label             matlab.ui.control.Label
        ViewDropDown_2                  matlab.ui.control.DropDown
        LogoutButton_7                  matlab.ui.control.Button
        HomeButton_2                    matlab.ui.control.Button
        MedProfPTComparisonViewTab      matlab.ui.container.Tab
        EMRAPPatientSearchResultsComparisonLabel  matlab.ui.control.Label
        UITable5                        matlab.ui.control.Table
        SearchAgainButton_3             matlab.ui.control.Button
        LogoutButton_8                  matlab.ui.control.Button
        HomeButton_3                    matlab.ui.control.Button
        MedProfCircuitComparisonViewTab  matlab.ui.container.Tab
        EMRAPCircuitSearchResultsComparisonLabel  matlab.ui.control.Label
        UITable5_3                      matlab.ui.control.Table
        SearchAgainButton_6             matlab.ui.control.Button
        LogoutButton_9                  matlab.ui.control.Button
        HomeButton_4                    matlab.ui.control.Button
        MedProfPTTimelineViewTab        matlab.ui.container.Tab
        EMRAPPatientSearchResultsTimelineLabel  matlab.ui.control.Label
        UITable5_2                      matlab.ui.control.Table
        SearchAgainButton_4             matlab.ui.control.Button
        TimelineUnitButton              matlab.ui.control.Button
        GraphViewButton                 matlab.ui.control.Button
        LogoutButton_10                 matlab.ui.control.Button
        HomeButton_5                    matlab.ui.control.Button
        MedProfCircuitTimelineViewTab   matlab.ui.container.Tab
        EMRAPCircuitSearchResultsTimelineLabel  matlab.ui.control.Label
        UITable5_4                      matlab.ui.control.Table
        SearchAgainButton_7             matlab.ui.control.Button
        TimelineUnitButton_2            matlab.ui.control.Button
        GraphViewButton_2               matlab.ui.control.Button
        LogoutButton_11                 matlab.ui.control.Button
        HomeButton_6                    matlab.ui.control.Button
        MedProfPTTimelineViewTab_2      matlab.ui.container.Tab
        EMRAPPatientSearchResultsTimelineLabel_2  matlab.ui.control.Label
        SearchAgainButton_5             matlab.ui.control.Button
        XAxisValueUnitButton            matlab.ui.control.Button
        TableViewButton                 matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        YAxisValueUnitButton            matlab.ui.control.Button
        LogoutButton_12                 matlab.ui.control.Button
        HomeButton_7                    matlab.ui.control.Button
        MedProfCircuitTimelineViewTab_2  matlab.ui.container.Tab
        EMRAPCircuitSearchResultsTimelineLabel_2  matlab.ui.control.Label
        SearchAgainButton_8             matlab.ui.control.Button
        XAxisValueUnitButton_2          matlab.ui.control.Button
        TableViewButton_2               matlab.ui.control.Button
        UIAxes2_2                       matlab.ui.control.UIAxes
        YAxisValueUnitButton_2          matlab.ui.control.Button
        LogoutButton_13                 matlab.ui.control.Button
        HomeButton_8                    matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        MedProfGeneralSearchTab         matlab.ui.container.Tab
        WhatareyouinterestedinlearningEditFieldLabel  matlab.ui.control.Label
        WhatareyouinterestedinlearningEditField  matlab.ui.control.EditField
        UITable2                        matlab.ui.control.Table
        SearchButton                    matlab.ui.control.Button
        EMRAPAnswersLabel               matlab.ui.control.Label
        ResultingQueryTextAreaLabel     matlab.ui.control.Label
        SQL_Result_String               matlab.ui.control.TextArea
        LogoutButton_14                 matlab.ui.control.Button
        HomeButton_9                    matlab.ui.control.Button
        GotoChartButton                 matlab.ui.control.Button
        PatientViewTab                  matlab.ui.container.Tab
        PatientViewLabel                matlab.ui.control.Label
        WhoAreyouEditFieldLabel         matlab.ui.control.Label
        WhoAreyouEditField              matlab.ui.control.EditField
        LogoutButton_15                 matlab.ui.control.Button
        HomeButton_10                   matlab.ui.control.Button
        NetworkMxTab                    matlab.ui.container.Tab
        SystemMaintenanceLabel          matlab.ui.control.Label
        FixStuffSliderLabel             matlab.ui.control.Label
        FixStuffSlider                  matlab.ui.control.Slider
        FixThisStuffButtonGroup         matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        Button2                         matlab.ui.control.RadioButton
        Button3                         matlab.ui.control.RadioButton
        Tree                            matlab.ui.container.Tree
        WhatsBrokeNode                  matlab.ui.container.TreeNode
        Node2                           matlab.ui.container.TreeNode
        Node3                           matlab.ui.container.TreeNode
        Node4                           matlab.ui.container.TreeNode
        LogoutButton_16                 matlab.ui.control.Button
        HomeButton_11                   matlab.ui.control.Button
        Tab                             matlab.ui.container.Tab
    end

    methods (Access = private)

        % Value changed function: 
        % WhatareyouinterestedinlearningEditField
        function RunClassificationElso(app, event)
            d= uiprogressdlg(app.UIFigure,'Title','Running Query','Indeterminate','on','Message','Running Query...');
            value = app.WhatareyouinterestedinlearningEditField.Value;
            [sqlquery,returnTable]=ClassificationElso(value);
            
            app.UITable2.Data=returnTable;
            VarNames=returnTable.Properties.VariableNames;
            app.UITable2.ColumnName=VarNames;
            
            app.SQL_Result_String.Value=sqlquery;
            
            
            if width(returnTable)==2 %create histogram/ pie chart
                
                if iscategorical(returnTable.(VarNames{2})(1))
                    %a=categories(returnTable.(VarNames{2}));
                    %b=countcats(returnTable.(VarNames{2}));
                    pie(app.UIAxes2_2,returnTable.(VarNames{2}))
                    
                    
                else
                    
                histogram(app.UIAxes2_2,returnTable.(2));
                app.UIAxes2_2.YLabel.String='Number of Records';
                app.UIAxes2_2.XLabel.String=returnTable.Properties.VariableNames{2};
                
                % do some statistics              
                
                avgVal=mean(returnTable.(2)); %figure out how to turn duration into double without really knowing...
                maxVal=max(returnTable.(2));
                minVal=min(returnTable.(2));
                stdevVal=std(returnTable.(2));


                avgString=["Average = " string(hours(avgVal))];
                maxString=["Max Value = " string(hours(maxVal))];
                minString=["Min Value = " string(hours(minVal))];
                stdString=["Standard Deviation = " string(hours(stdevVal))];
                
                textstring=[avgString,
                                    maxString,
                                    minString,
                                    stdString];
                                textstring2=join(textstring)
                app.TextArea.Value=textstring2;
                                
%                 text(app.UIAxes2_2,200,200,avgString);
%                 text(app.UIAxes2_2,200,190,maxString);
%                 text(app.UIAxes2_2,200,180,minString);
%                 text(app.UIAxes2_2,200,170,stdString);
                end
                
            end
            
            close(d);
            
        end

        % Button pushed function: ViewHIPAAPrivacyInformationButton
        function LaunchHipaaSite(app, event)
            web('https://www.hhs.gov/hipaa/index.html','-new','-browser');
        end

        % Button pushed function: LoginButton
        function LoginFunction(app, event)
            % make sure a user name and pwd has been entered
            
            if ~isempty(app.WhoAreYouEditField.Value) && strcmp('PMASEecmo',app.PasswordEditField.Value)
               
                
                if strcmp(app.AccessLevelDropDown.Value,'Admin')
                    app.TabGroup.SelectedTab=app.AdminTab;
                elseif strcmp(app.AccessLevelDropDown.Value,'Medical Professional')
                    app.TabGroup.SelectedTab=app.MedProfGeneralSearchTab;
                    
                elseif strcmp(app.AccessLevelDropDown.Value, 'Patient')
                    app.TabGroup.SelectedTab=app.PatientViewTab;
                elseif strcmp(app.AccessLevelDropDown.Value,'Mx')
                    app.TabGroup.SelectedTab=app.AdminTab;
                else
                    %do nothing.
                    
                end
                
                app.PasswordEditField.Value=' ';
            else
                
                uialert(app.UIFigure,'Please enter a Valid User Name and Password','Invalid Credentials');
                
            end
        end

        % Button pushed function: GoGetitButton
        function SearchEMR(app, event)
            d=uiprogressdlg(app.UIFigure,'Title','Running Query','Indeterminate','on','Message','Running Query...');
            try
            patient_data=patient_query_1(app.PatientLastNameEditField.Value,app.PatientFirstNameEditField_2.Value);
            
            
            app.UITable5_2.Data=patient_data;
            app.UITable5_2.ColumnName=patient_data.Properties.VariableNames;
            
            app.TabGroup.SelectedTab=app.MedProfPTTimelineViewTab;
           
            
            close(d);
            
            catch
                close(d);
                uialert(app.UIFigure,'No Data to return. Check spelling of Name','Invalid Query');
            end
            
        end

        % Button pushed function: LogoutButton_10, LogoutButton_11, 
        % LogoutButton_12, LogoutButton_13, LogoutButton_14, 
        % LogoutButton_15, LogoutButton_16, LogoutButton_3, 
        % LogoutButton_4, LogoutButton_5, LogoutButton_6, LogoutButton_9
        function LogoutFcn(app, event)
            app.TabGroup.SelectedTab=app.SplashPageTab;
        end

        % Button pushed function: HomeButton, HomeButton_10, 
        % HomeButton_11, HomeButton_2, HomeButton_3, HomeButton_4, 
        % HomeButton_5, HomeButton_6, HomeButton_7, HomeButton_8, 
        % HomeButton_9
        function HomeFcn(app, event)
            app.TabGroup.SelectedTab=app.SplashPageTab;
        end

        % Button pushed function: GotoChartButton
        function GotoChart(app, event)
            app.TabGroup.SelectedTab=app.MedProfCircuitTimelineViewTab_2;
        end

        % Button pushed function: SearchAgainButton_8
        function GoToSearch(app, event)
            app.TabGroup.SelectedTab=app.MedProfGeneralSearchTab;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InsertQueryHereLabel = uilabel(app.UIFigure);
            app.InsertQueryHereLabel.Text = 'Insert Query Here';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SplashPageTab = uitab(app.TabGroup);
            app.SplashPageTab.Title = 'Splash Page';
            app.ElectronicMedicalRecordAccessPortalEMRAPLabel = uilabel(app.SplashPageTab);
            app.ElectronicMedicalRecordAccessPortalEMRAPLabel.Text = {'Electronic Medical Record Access Portal'; '(EMRAP)'; ''};
            app.AccessLevelDropDownLabel = uilabel(app.SplashPageTab);
            app.AccessLevelDropDownLabel.Text = 'Access Level';
            app.AccessLevelDropDown = uidropdown(app.SplashPageTab);
            app.AccessLevelDropDown.Items = {'Medical Professional', 'Patient', 'Admin', 'Mx', 'Special'};
            app.AccessLevelDropDown.Value = 'Medical Professional';
            app.SubmitTroubleTicketButton = uibutton(app.SplashPageTab, 'push');
            app.SubmitTroubleTicketButton.Text = 'Submit Trouble Ticket';
            app.ViewHIPAAPrivacyInformationButton = uibutton(app.SplashPageTab, 'push');
            app.ViewHIPAAPrivacyInformationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchHipaaSite, true);
            app.ViewHIPAAPrivacyInformationButton.Text = 'View HIPAA Privacy Information';
            app.LoginButton = uibutton(app.SplashPageTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginFunction, true);
            app.LoginButton.Text = 'Login';
            app.WhoAreYouEditFieldLabel = uilabel(app.SplashPageTab);
            app.WhoAreYouEditFieldLabel.Text = 'Who Are You?';
            app.WhoAreYouEditField = uieditfield(app.SplashPageTab, 'text');
            app.PasswordEditFieldLabel = uilabel(app.SplashPageTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.SplashPageTab, 'text');
            app.AdminTab = uitab(app.TabGroup);
            app.AdminTab.Title = 'Admin';
            app.PersonnelActionsDropDownLabel = uilabel(app.AdminTab);
            app.PersonnelActionsDropDownLabel.Text = 'Personnel Actions';
            app.PersonnelActionsDropDown = uidropdown(app.AdminTab);
            app.PersonnelActionsDropDown.Items = {'Add User', 'Remove User', 'Modify User', 'Search Access', 'View User List', 'N/A'};
            app.PersonnelActionsDropDown.Value = 'Add User';
            app.EMRAPAdminLabel = uilabel(app.AdminTab);
            app.EMRAPAdminLabel.Text = 'EMRAP Admin';
            app.SystemActionsDropDownLabel = uilabel(app.AdminTab);
            app.SystemActionsDropDownLabel.Text = 'System Actions';
            app.SystemActionsDropDown = uidropdown(app.AdminTab);
            app.SystemActionsDropDown.Items = {'Add Circuit', 'Remove Circuit', 'Modify Circuit', 'N/A'};
            app.SystemActionsDropDown.Value = 'Add Circuit';
            app.NameSSNEditFieldLabel = uilabel(app.AdminTab);
            app.NameSSNEditFieldLabel.Text = 'Name/SSN';
            app.NameSSNEditField = uieditfield(app.AdminTab, 'text');
            app.CircuitCriteriaEditFieldLabel = uilabel(app.AdminTab);
            app.CircuitCriteriaEditFieldLabel.Text = 'Circuit Criteria';
            app.CircuitCriteriaEditField = uieditfield(app.AdminTab, 'text');
            app.EnterButton = uibutton(app.AdminTab, 'push');
            app.EnterButton.Text = 'Enter';
            app.EnterButton_2 = uibutton(app.AdminTab, 'push');
            app.EnterButton_2.Text = 'Enter';
            app.SwitchRoleButton = uibutton(app.AdminTab, 'push');
            app.SwitchRoleButton.Text = 'Switch Role';
            app.RoleDropDownLabel = uilabel(app.AdminTab);
            app.RoleDropDownLabel.Text = 'Role';
            app.RoleDropDown = uidropdown(app.AdminTab);
            app.RoleDropDown.Items = {'Medical Professional', 'Patient', 'Admin', 'Mx', 'Special'};
            app.RoleDropDown.Value = 'Medical Professional';
            app.CircuitLocationEditFieldLabel = uilabel(app.AdminTab);
            app.CircuitLocationEditFieldLabel.Text = 'Circuit Location';
            app.CircuitLocationEditField = uieditfield(app.AdminTab, 'text');
            app.LogoutButton_3 = uibutton(app.AdminTab, 'push');
            app.LogoutButton_3.ButtonPushedFcn = createCallbackFcn(app, @LogoutFcn, true);
            app.LogoutButton_3.Text = 'Logout';
            app.AdminPersonnelTab = uitab(app.TabGroup);
            app.AdminPersonnelTab.Title = 'Admin Personnel';
            app.EMRAPAdminPersonnelResultsLabel = uilabel(app.AdminPersonnelTab);
            app.EMRAPAdminPersonnelResultsLabel.Text = 'EMRAP Admin Personnel Results';
            app.UITable3 = uitable(app.AdminPersonnelTab);
            app.UITable3.ColumnName = {'User'; 'Role'; 'Expiration'};
            app.UITable3.RowName = {};
            app.SearchAgainButton = uibutton(app.AdminPersonnelTab, 'push');
            app.SearchAgainButton.Text = 'Search Again';
            app.LogoutButton_4 = uibutton(app.AdminPersonnelTab, 'push');
            app.LogoutButton_4.ButtonPushedFcn = createCallbackFcn(app, @LogoutFcn, true);
            app.LogoutButton_4.Text = 'Logout';
            app.AdminCircuitTab = uitab(app.TabGroup);
            app.AdminCircuitTab.Title = 'Admin Circuit ';
            app.EMRAPAdminCircuitResultsLabel = uilabel(app.AdminCircuitTab);
            app.EMRAPAdminCircuitResultsLabel.Text = 'EMRAP Admin Circuit Results';
            app.UITable4 = uitable(app.AdminCircuitTab);
            app.UITable4.ColumnName = {'Circuit '; 'Previous Data'; 'Updated Data'};
            app.UITable4.RowName = {};
            app.SearchAgainButton_2 = uibutton(app.AdminCircuitTab, 'push');
            app.SearchAgainButton_2.Text = 'Search Again';
            app.LogoutButton_5 = uibutton(app.AdminCircuitTab, 'push');
            app.LogoutButton_5.ButtonPushedFcn = createCallbackFcn(app, @LogoutFcn, true);
            app.LogoutButton_5.Text = 'Logout';
            app.MedProfPatientSearchTab = uitab(app.TabGroup);
            app.MedProfPatientSearchTab.Title = 'Med Prof Patient Search';
            app.PatientLastNameEditFieldLabel = uilabel(app.MedProfPatientSearchTab);
            app.PatientLastNameEditFieldLabel.Text = 'Patient Last Name';
            app.PatientLastNameEditField = uieditfield(app.MedProfPatientSearchTab, 'text');
            app.DataofInterestEditFieldLabel = uilabel(app.MedProfPatientSearchTab);
            app.DataofInterestEditFieldLabel.Text = 'Data of Interest';
            app.DataofInterestEditField = uieditfield(app.MedProfPatientSearchTab, 'text');
            app.GoGetitButton = uibutton(app.MedProfPatientSearchTab, 'push');
            app.GoGetitButton.ButtonPushedFcn = createCallbackFcn(app, @SearchEMR, true);
            app.GoGetitButton.Text = 'Go Get it!';
            app.EMRAPPatientSearchLabel = uilabel(app.MedProfPatientSearchTab);
            app.EMRAPPatientSearchLabel.Text = 'EMRAP Patient Search';
            app.ViewDropDownLabel = uilabel(app.MedProfPatientSearchTab);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.MedProfPatientSearchTab);
            app.ViewDropDown.Items = {'Comparison', 'Timeline'};
            app.ViewDropDown.Value = 'Comparison';
            app.PatientFirstNameEditField_2Label = uilabel(app.MedProfPatientSearchTab);
            app.PatientFirstNameEditField_2Label.Text = 'Patient First Name';
            app.PatientFirstNameEditField_2 = uieditfield(app.MedProfPatientSearchTab, 'text');
            app.LogoutButton_6 = uibutton(app.MedProfPatientSearchTab, 'push');
            app.LogoutButton_6.ButtonPushedFcn = createCallbackFcn(app, @LogoutFcn, true);
            app.LogoutButton_6.Text = 'Logout';
            app.HomeButton = uibutton(app.MedProfPatientSearchTab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeFcn, true);
            app.HomeButton.Text = 'Home';
            app.MedProfCircuitSearchTab = uitab(app.TabGroup);
            app.MedProfCircuitSearchTab.Title = 'Med Prof Circuit Search';
            app.CircuitIDsEditFieldLabel = uilabel(app.MedProfCircuitSearchTab);
            app.CircuitIDsEditFieldLabel.Text = 'Circuit ID(s)';
            app.CircuitIDsEditField = uieditfield(app.MedProfCircuitSearchTab, 'text');
            app.DataofInterestEditField_2Label = uilabel(app.MedProfCircuitSearchTab);
            app.DataofInterestEditField_2Label.Text = 'Data of Interest';
            app.DataofInterestEditField_2 = uieditfield(app.MedProfCircuitSearchTab, 'text');
            app.GoGetitButton_2 = uibutton(app.MedProfCircuitSearchTab, 'push');
            app.GoGetitButton_2.Text = 'Go Get it!';
            app.EMRAPCircuitSearchLabel = uilabel(app.MedProfCircuitSearchTab);
            app.EMRAPCircuitSearchLabel.Text = 'EMRAP Circuit Search';
            app.ViewDropDown_2Label = uilabel(app.MedProfCircuitSearchTab);
            app.ViewDropDown_2Label.Text = 'View';
            app.ViewDropDown_2 = uidropdown(app.MedProfCircuitSearchTab);
            app.ViewDropDown_2.Items = {'Comparison', 'Timeline'};
            app.ViewDropDown_2.Value = 'Comparison';
            app.LogoutButton_7 = uibutton(app.MedProfCircuitSearchTab, 'push');
            app.LogoutButton_7.Text = 'Logout';
            app.HomeButton_2 = uibutton(app.MedProfCircuitSearchTab, 'push');
            app.HomeButton_2.ButtonPushedFcn = createCallbackFcn(app, @HomeFcn, true);
            app.HomeButton_2.Text = 'Home';
            app.MedProfPTComparisonViewTab = uitab(app.TabGroup);
            app.MedProfPTComparisonViewTab.Title = 'Med Prof PT Comparison View';
            app.EMRAPPatientSearchResultsComparisonLabel = uilabel(app.MedProfPTComparisonViewTab);
            app.EMRAPPatientSearchResultsComparisonLabel.Text = {'EMRAP Patient Search Results'; '(Comparison)'};
            app.UITable5 = uitable(app.MedProfPTComparisonViewTab);
            app.UITable5.ColumnName = {'PT ID'; 'Pre-exist Cond'; 'Current Condition'; 'Outcome'};
            app.UITable5.RowName = {};
            app.SearchAgainButton_3 = uibutton(app.MedProfPTComparisonViewTab, 'push');
            app.SearchAgainButton_3.Text = 'Search Again';
            app.LogoutButton_8 = uibutton(app.MedProfPTComparisonViewTab, 'push');
            app.LogoutButton_8.Text = 'Logout';
            app.HomeButton_3 = uibutton(app.MedProfPTComparisonViewTab, 'push');
            app.HomeButton_3.ButtonPushedFcn = createCallbackFcn(app, @HomeFcn, true);
            app.HomeButton_3.Text = 'Home';
            app.MedProfCircuitComparisonViewTab = uitab(app.TabGroup);
            app.MedProfCircuitComparisonViewTab.Title = 'Med Prof Circuit Comparison View';
            app.EMRAPCircuitSearchResultsComparisonLabel = uilabel(app.MedProfCircuitComparisonViewTab);
            app.EMRAPCircuitSearchResultsComparisonLabel.Text = {'EMRAP Circuit Search Results'; '(Comparison)'};
            app.UITable5_3 = uitable(app.MedProfCircuitComparisonViewTab);
            app.UITable5_3.ColumnName = {'PT ID'; 'Pre-exist Cond'; 'Current Condition'; 'Outcome'};
            app.UITable5_3.RowName = {};
            app.SearchAgainButton_6 = uibutton(app.MedProfCircuitComparisonViewTab, 'push');
            app.SearchAgainButton_6.Text = 'Search Again';
            app.LogoutButton_9 = uibutton(app.MedProfCircuitComparisonViewTab, 'push');
            app.LogoutButton_9.ButtonPushedFcn = createCallbackFcn(app, @LogoutFcn, true);
            app.LogoutButton_9.Text = 'Logout';
            app.HomeButton_4 = uibutton(app.MedProfCircuitComparisonViewTab, 'push');
            app.HomeButton_4.ButtonPushedFcn = createCallbackFcn(app, @HomeFcn, true);
            app.HomeButton_4.Text = 'Home';
            app.MedProfPTTimelineViewTab = uitab(app.TabGroup);
            app.MedProfPTTimelineViewTab.Title = 'Med Prof PT Timeline View';
            app.EMRAPPatientSearchResultsTimelineLabel = uilabel(app.MedProfPTTimelineViewTab);
            app.EMRAPPatientSearchResultsTimelineLabel.Text = {'EMRAP Patient Search Results'; '(Timeline)'};
            app.UITable5_2 = uitable(app.MedProfPTTimelineViewTab);
            app.UITable5_2.ColumnName = {'PT ID'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '1'; '1'; '2'; '3'};
            app.UITable5_2.RowName = {};
            app.UITable5_2.ColumnEditable = [false false false false false false false false false true false false false];
            app.SearchAgainButton_4 = uibutton(app.MedProfPTTimelineViewTab, 'push');
            app.SearchAgainButton_4.Text = 'Search Again';
            app.TimelineUnitButton = uibutton(app.MedProfPTTimelineViewTab, 'push');
            app.TimelineUnitButton.Text = 'Timeline Unit';
            app.GraphViewButton = uibutton(app.MedProfPTTimelineViewTab, 'push');
            app.GraphViewButton.Text = 'Graph View';
            app.LogoutButton_10 = uibutton(app.MedProfPTTimelineViewTab, 'push');
            app.LogoutButton_10.ButtonPushedFcn = createCallbackFcn(app, @LogoutFcn, true);
            app.LogoutButton_10.Text = 'Logout';
            app.HomeButton_5 = uibutton(app.MedProfPTTimelineViewTab, 'push');
            app.HomeButton_5.ButtonPushedFcn = createCallbackFcn(app, @HomeFcn, true);
            app.HomeButton_5.Text = 'Home';
            app.MedProfCircuitTimelineViewTab = uitab(app.TabGroup);
            app.MedProfCircuitTimelineViewTab.Title = 'Med Prof Circuit Timeline View';
            app.EMRAPCircuitSearchResultsTimelineLabel = uilabel(app.MedProfCircuitTimelineViewTab);
            app.EMRAPCircuitSearchResultsTimelineLabel.Text = {'EMRAP Circuit Search Results'; '(Timeline)'};
            app.UITable5_4 = uitable(app.MedProfCircuitTimelineViewTab);
            app.UITable5_4.ColumnName = {'PT ID'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '1'; '1'; '2'; '3'};
            app.UITable5_4.RowName = {};
            app.UITable5_4.ColumnEditable = [false false false false false false false false false true false false false];
            app.SearchAgainButton_7 = uibutton(app.MedProfCircuitTimelineViewTab, 'push');
            app.SearchAgainButton_7.Text = 'Search Again';
            app.TimelineUnitButton_2 = uibutton(app.MedProfCircuitTimelineViewTab, 'push');
            app.TimelineUnitButton_2.Text = 'Timeline Unit';
            app.GraphViewButton_2 = uibutton(app.MedProfCircuitTimelineViewTab, 'push');
            app.GraphViewButton_2.Text = 'Graph View';
            app.LogoutButton_11 = uibutton(app.MedProfCircuitTimelineViewTab, 'push');
            app.LogoutButton_11.ButtonPushedFcn = createCallbackFcn(app, @LogoutFcn, true);
            app.LogoutButton_11.Text = 'Logout';
            app.HomeButton_6 = uibutton(app.MedProfCircuitTimelineViewTab, 'push');
            app.HomeButton_6.ButtonPushedFcn = createCallbackFcn(app, @HomeFcn, true);
            app.HomeButton_6.Text = 'Home';
            app.MedProfPTTimelineViewTab_2 = uitab(app.TabGroup);
            app.MedProfPTTimelineViewTab_2.Title = 'Med Prof PT Timeline View';
            app.EMRAPPatientSearchResultsTimelineLabel_2 = uilabel(app.MedProfPTTimelineViewTab_2);
            app.EMRAPPatientSearchResultsTimelineLabel_2.Text = {'EMRAP Patient Search Results'; '(Timeline)'};
            app.SearchAgainButton_5 = uibutton(app.MedProfPTTimelineViewTab_2, 'push');
            app.SearchAgainButton_5.Text = 'Search Again';
            app.XAxisValueUnitButton = uibutton(app.MedProfPTTimelineViewTab_2, 'push');
            app.XAxisValueUnitButton.Text = 'X-Axis Value Unit';
            app.TableViewButton = uibutton(app.MedProfPTTimelineViewTab_2, 'push');
            app.TableViewButton.Text = 'Table View';
            app.UIAxes2 = uiaxes(app.MedProfPTTimelineViewTab_2);
            title(app.UIAxes2, 'Patient Timeline')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.YAxisValueUnitButton = uibutton(app.MedProfPTTimelineViewTab_2, 'push');
            app.YAxisValueUnitButton.Text = 'Y-Axis Value Unit';
            app.LogoutButton_12 = uibutton(app.MedProfPTTimelineViewTab_2, 'push');
            app.LogoutButton_12.ButtonPushedFcn = createCallbackFcn(app, @LogoutFcn, true);
            app.LogoutButton_12.Text = 'Logout';
            app.HomeButton_7 = uibutton(app.MedProfPTTimelineViewTab_2, 'push');
            app.HomeButton_7.ButtonPushedFcn = createCallbackFcn(app, @HomeFcn, true);
            app.HomeButton_7.Text = 'Home';
            app.MedProfCircuitTimelineViewTab_2 = uitab(app.TabGroup);
            app.MedProfCircuitTimelineViewTab_2.Title = 'Med Prof Circuit Timeline View';
            app.EMRAPCircuitSearchResultsTimelineLabel_2 = uilabel(app.MedProfCircuitTimelineViewTab_2);
            app.EMRAPCircuitSearchResultsTimelineLabel_2.Text = {'EMRAP Circuit Search Results'; '(Timeline)'};
            app.SearchAgainButton_8 = uibutton(app.MedProfCircuitTimelineViewTab_2, 'push');
            app.SearchAgainButton_8.ButtonPushedFcn = createCallbackFcn(app, @GoToSearch, true);
            app.SearchAgainButton_8.Text = 'Search Again';
            app.XAxisValueUnitButton_2 = uibutton(app.MedProfCircuitTimelineViewTab_2, 'push');
            app.XAxisValueUnitButton_2.Text = 'X-Axis Value Unit';
            app.TableViewButton_2 = uibutton(app.MedProfCircuitTimelineViewTab_2, 'push');
            app.TableViewButton_2.Text = 'Table View';
            app.UIAxes2_2 = uiaxes(app.MedProfCircuitTimelineViewTab_2);
            title(app.UIAxes2_2, 'Circuit Timeline')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.YAxisValueUnitButton_2 = uibutton(app.MedProfCircuitTimelineViewTab_2, 'push');
            app.YAxisValueUnitButton_2.Text = 'Y-Axis Value Unit';
            app.LogoutButton_13 = uibutton(app.MedProfCircuitTimelineViewTab_2, 'push');
            app.LogoutButton_13.ButtonPushedFcn = createCallbackFcn(app, @LogoutFcn, true);
            app.LogoutButton_13.Text = 'Logout';
            app.HomeButton_8 = uibutton(app.MedProfCircuitTimelineViewTab_2, 'push');
            app.HomeButton_8.ButtonPushedFcn = createCallbackFcn(app, @HomeFcn, true);
            app.HomeButton_8.Text = 'Home';
            app.TextArea = uitextarea(app.MedProfCircuitTimelineViewTab_2);
            app.MedProfGeneralSearchTab = uitab(app.TabGroup);
            app.MedProfGeneralSearchTab.Title = 'Med Prof General Search';
            app.WhatareyouinterestedinlearningEditFieldLabel = uilabel(app.MedProfGeneralSearchTab);
            app.WhatareyouinterestedinlearningEditFieldLabel.Text = 'What are you interested in learning?';
            app.WhatareyouinterestedinlearningEditField = uieditfield(app.MedProfGeneralSearchTab, 'text');
            app.WhatareyouinterestedinlearningEditField.ValueChangedFcn = createCallbackFcn(app, @RunClassificationElso, true);
            app.UITable2 = uitable(app.MedProfGeneralSearchTab);
            app.UITable2.ColumnName = {'Results'; ''};
            app.UITable2.RowName = {};
            app.SearchButton = uibutton(app.MedProfGeneralSearchTab, 'push');
            app.SearchButton.Text = 'Search';
            app.EMRAPAnswersLabel = uilabel(app.MedProfGeneralSearchTab);
            app.EMRAPAnswersLabel.Text = 'EMRAP Answers';
            app.ResultingQueryTextAreaLabel = uilabel(app.MedProfGeneralSearchTab);
            app.ResultingQueryTextAreaLabel.Text = 'Resulting Query';
            app.SQL_Result_String = uitextarea(app.MedProfGeneralSearchTab);
            app.LogoutButton_14 = uibutton(app.MedProfGeneralSearchTab, 'push');
            app.LogoutButton_14.ButtonPushedFcn = createCallbackFcn(app, @LogoutFcn, true);
            app.LogoutButton_14.Text = 'Logout';
            app.HomeButton_9 = uibutton(app.MedProfGeneralSearchTab, 'push');
            app.HomeButton_9.ButtonPushedFcn = createCallbackFcn(app, @HomeFcn, true);
            app.HomeButton_9.Text = 'Home';
            app.GotoChartButton = uibutton(app.MedProfGeneralSearchTab, 'push');
            app.GotoChartButton.ButtonPushedFcn = createCallbackFcn(app, @GotoChart, true);
            app.GotoChartButton.Text = 'Goto Chart';
            app.PatientViewTab = uitab(app.TabGroup);
            app.PatientViewTab.Title = 'Patient View';
            app.PatientViewLabel = uilabel(app.PatientViewTab);
            app.PatientViewLabel.Text = 'Patient View';
            app.WhoAreyouEditFieldLabel = uilabel(app.PatientViewTab);
            app.WhoAreyouEditFieldLabel.Text = 'Who Are you?';
            app.WhoAreyouEditField = uieditfield(app.PatientViewTab, 'text');
            app.LogoutButton_15 = uibutton(app.PatientViewTab, 'push');
            app.LogoutButton_15.ButtonPushedFcn = createCallbackFcn(app, @LogoutFcn, true);
            app.LogoutButton_15.Text = 'Logout';
            app.HomeButton_10 = uibutton(app.PatientViewTab, 'push');
            app.HomeButton_10.ButtonPushedFcn = createCallbackFcn(app, @HomeFcn, true);
            app.HomeButton_10.Text = 'Home';
            app.NetworkMxTab = uitab(app.TabGroup);
            app.NetworkMxTab.Title = 'Network Mx';
            app.SystemMaintenanceLabel = uilabel(app.NetworkMxTab);
            app.SystemMaintenanceLabel.Text = 'System Maintenance';
            app.FixStuffSliderLabel = uilabel(app.NetworkMxTab);
            app.FixStuffSliderLabel.Text = 'Fix Stuff';
            app.FixStuffSlider = uislider(app.NetworkMxTab);
            app.FixThisStuffButtonGroup = uibuttongroup(app.NetworkMxTab);
            app.FixThisStuffButtonGroup.Title = 'Fix This Stuff';
            app.Button = uiradiobutton(app.FixThisStuffButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.FixThisStuffButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.FixThisStuffButtonGroup);
            app.Button3.Text = 'Button3';
            app.Tree = uitree(app.NetworkMxTab);
            app.WhatsBrokeNode = uitreenode(app.Tree);
            app.WhatsBrokeNode.Text = 'What''s Broke?';
            app.Node2 = uitreenode(app.WhatsBrokeNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.WhatsBrokeNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.WhatsBrokeNode);
            app.Node4.Text = 'Node4';
            app.LogoutButton_16 = uibutton(app.NetworkMxTab, 'push');
            app.LogoutButton_16.ButtonPushedFcn = createCallbackFcn(app, @LogoutFcn, true);
            app.LogoutButton_16.Text = 'Logout';
            app.HomeButton_11 = uibutton(app.NetworkMxTab, 'push');
            app.HomeButton_11.ButtonPushedFcn = createCallbackFcn(app, @HomeFcn, true);
            app.HomeButton_11.Text = 'Home';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BasicGUIIdea

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end